classdef grafice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ResultsLabel     matlab.ui.control.Label
        torsorResults_6  matlab.ui.control.UIAxes
        torsorResults_5  matlab.ui.control.UIAxes
        torsorResults_4  matlab.ui.control.UIAxes
        torsorResults_3  matlab.ui.control.UIAxes
        torsorResults_2  matlab.ui.control.UIAxes
        torsorResults    matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
        function results = robotSimulate(app, distance1, distance2, distance3, timp, pas)
            t=0:pas:timp;
            lungime = length(t);
            q1v = PI_Calcul(app, lungime, distance1, pas);
            q2v = PI_Calcul(app, lungime, distance2, pas);
            q3v = PI_Calcul(app, lungime, distance3, pas);
            q1=[t; q1v]'; % z
            q2=[t; q2v]'; % x
            q3=[t; q3v]'; % translatie a 3 cupla

            assignin('base', "timp", timp)
            assignin('base', 'q1', q1);
            assignin('base', 'q2', q2);
            assignin('base', 'q3', q3);
            results = sim('brat_robotic.slx', 'SimulationMode', 'normal');
        end

        function results = PI_Calcul(app, lungime, distance, pas)
            results = zeros(1, lungime);
            results(1) =0;
            Kp = 0.009;
            Ki = 1.9;
            medie = 0;
            error = 0;
            for i=2:lungime
                error = distance - results(i-1);
                Pout = Kp * error;
                medie = medie + error* pas;
                Iout = Ki * medie;
                results(i) = Pout + Iout ;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             cla(app.torsorResults);
             cla(app.torsorResults_2);
             global q1;
             global q2;
             global q3;
            try
                results = robotSimulate(app, q1, q2, q3, 10, 0.0001);
            catch e
                uialert(app.UIFigure, e.message, 'Simulare Eșuată');
                return;
            end
            
            %Valorile torsor 1
            AsimulateResultsX = [squeeze(results.moment1.data(1, :, :))];
            AsimulateResultsY = [squeeze(results.moment1.data(2, :, :))];
            AsimulateResultsZ = [squeeze(results.moment1.data(3, :, :))];
            simTime1 = [results.moment1.time];
            simulateResultsX_first_tor_1 = [simTime1(1:20) AsimulateResultsX(1:20) simTime1(1:20) AsimulateResultsY(1:20) simTime1(1:20) AsimulateResultsZ(1:20)];
            simulateResultsX_second_tor_1 = [simTime1(21:end) AsimulateResultsX(21:end) simTime1(21:end) AsimulateResultsY(21:end) simTime1(21:end) AsimulateResultsZ(21:end)];

            %Valorile torsor 2
            %BsimulateResultsX = [squeeze(results.moment2.data(1, :, :))];
            %BsimulateResultsY = [squeeze(results.moment2.data(2, :, :))];
            %BsimulateResultsZ = [squeeze(results.moment2.data(3, :, :))];
            %simTime2 = [results.moment2.time];
            %simulateResultsX_first_tor_2 = [simTime2(1:20) BsimulateResultsX(1:20) simTime2(1:20) BsimulateResultsY(1:20) simTime2(1:20) BsimulateResultsZ(1:20)];
            %simulateResultsX_second_tor_2 = [simTime2(21:end) BsimulateResultsX(21:end) simTime2(21:end) BsimulateResultsY(21:end) simTime2(21:end) BsimulateResultsZ(21:end)];


            %Valorile torsor 3
            %CsimulateResultsX = [squeeze(results.moment3.data(1, :, :))];
            %CsimulateResultsY = [squeeze(results.moment3.data(2, :, :))];
            %CsimulateResultsZ = [squeeze(results.moment3.data(3, :, :))];
            %simTime3 = [results.moment3.time];
            %simulateResultsX_first_tor_3 = [simTime3(1:20) CsimulateResultsX(1:20) simTime3(1:20) CsimulateResultsY(1:20) simTime3(1:20) CsimulateResultsZ(1:20)];
            %simulateResultsX_second_tor_3 = [simTime3(21:end) CsimulateResultsX(21:end) simTime3(21:end) CsimulateResultsY(21:end) simTime3(21:end) CsimulateResultsZ(21:end)];

            
            hold(app.torsorResults, 'on')
            xlabel(app.torsorResults, "Timp");
            ylabel(app.torsorResults, "Valorile Torsorului 1 pentru primele 20 de iteratii");

            xlabel(app.torsorResults_2, "Timp");
            ylabel(app.torsorResults_2, "Valorile Torsorului 1 pentru restul iteratiilor");

            %xlabel(app.torsorResults_3, "Timp");
            %ylabel(app.torsorResults_3, "Valorile Torsorului 2 pentru primele 20 de iteratii");

            %xlabel(app.torsorResults_4, "Timp");
            %ylabel(app.torsorResults_4, "Valorile Torsorului 2 pentru restul iteratiilor");

            %xlabel(app.torsorResults_5, "Timp");
            %ylabel(app.torsorResults_5, "Valorile Torsorului 3 pentru primele 20 de iteratii");

            %xlabel(app.torsorResults_6, "Timp");
            %ylabel(app.torsorResults_6, "Valorile Torsorului 3 pentru restul iteratiilor");

            plot(app.torsorResults,   simulateResultsX_first_tor_1);
            plot(app.torsorResults_2, simulateResultsX_second_tor_1);
            %plot(app.torsorResults_3, simulateResultsX_first_tor_2);
            %plot(app.torsorResults_4, simulateResultsX_second_tor_2);
            %plot(app.torsorResults_5, simulateResultsX_first_tor_3);
            %plot(app.torsorResults_6, simulateResultsX_second_tor_3);
            hold(app.torsorResults, 'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.torsorResults = uiaxes(app.UIFigure);
            title(app.torsorResults, 'Title')
            xlabel(app.torsorResults, 'X')
            ylabel(app.torsorResults, 'Y')
            zlabel(app.torsorResults, 'Z')
            app.torsorResults_2 = uiaxes(app.UIFigure);
            title(app.torsorResults_2, 'Title')
            xlabel(app.torsorResults_2, 'X')
            ylabel(app.torsorResults_2, 'Y')
            zlabel(app.torsorResults_2, 'Z')
            app.torsorResults_3 = uiaxes(app.UIFigure);
            title(app.torsorResults_3, 'Title')
            xlabel(app.torsorResults_3, 'X')
            ylabel(app.torsorResults_3, 'Y')
            zlabel(app.torsorResults_3, 'Z')
            app.torsorResults_4 = uiaxes(app.UIFigure);
            title(app.torsorResults_4, 'Title')
            xlabel(app.torsorResults_4, 'X')
            ylabel(app.torsorResults_4, 'Y')
            zlabel(app.torsorResults_4, 'Z')
            app.torsorResults_5 = uiaxes(app.UIFigure);
            title(app.torsorResults_5, 'Title')
            xlabel(app.torsorResults_5, 'X')
            ylabel(app.torsorResults_5, 'Y')
            zlabel(app.torsorResults_5, 'Z')
            app.torsorResults_6 = uiaxes(app.UIFigure);
            title(app.torsorResults_6, 'Title')
            xlabel(app.torsorResults_6, 'X')
            ylabel(app.torsorResults_6, 'Y')
            zlabel(app.torsorResults_6, 'Z')
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grafice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end